classdef AiCam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AiCamUIFigure           matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        DevicesMenu             matlab.ui.container.Menu
        OptionsMenu             matlab.ui.container.Menu
        CaptureMenu             matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        PreviewTab              matlab.ui.container.Tab
        CameraListListBoxLabel  matlab.ui.control.Label
        CameraListListBox       matlab.ui.control.ListBox
        ResolutionsButtonGroup  matlab.ui.container.ButtonGroup
        PreviewButton           matlab.ui.control.Button
        CaptureRecordTab        matlab.ui.container.Tab
        ImageProcessTab         matlab.ui.container.Tab
    end

    
    properties (Access = public)
        glCamObj; % camera device obj
        glDevName % device name
        glSavePath % save capture image path
        glResolution % resolution for preview
        glCaptureFrames % frames will be capture
        glSaveFormat % save capture image formating
        glResAvalible % current camera avalibale resolutions
        glRsListTotal % total number of previous camera
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.glCaptureFrames = 1;
            app.glRsListTotal = 0;
            app.glSaveFormat = 'bmp';
            
            % List the camera device
            cl = webcamlist;
            n = size(cl);
            items = '';
            for i=1:n
                items = [items cl(i)];
                app.CameraListListBox.Items = items;
            end
        end

        % Value changed function: CameraListListBox
        function CameraListListBoxValueChanged(app, event)
            app.glDevName = app.CameraListListBox.Value;
            
            app.glCamObj = webcam(app.glDevName);
            rs = app.glCamObj.AvailableResolutions;
            [~, app.glResAvalible] = size(rs);
            
            
            left = 11;
            bottom = app.ResolutionsButtonGroup.Position(4);
            width = app.ResolutionsButtonGroup.Position(3);%ButtonGroup width
            height = 20;
            
            % hide the old list
            if (app.glRsListTotal ~= 0)
                for i=1:app.glRsListTotal
                    app.ResolutionsButtonGroup.Children(i).Visible =  'off';
                end
            end
            
            % cover the old list by new resolutions list
            for i=1:app.glResAvalible
                bt = uiradiobutton(app.ResolutionsButtonGroup);
                bt.Text = rs(i);
                bt.Position = [left bottom-25*i width height];
            end
            app.glRsListTotal = app.glResAvalible;
            str = sprintf('Resolutions(%d)', app.glResAvalible);
            app.ResolutionsButtonGroup.Title = str;
        end

        % Selection changed function: ResolutionsButtonGroup
        function ResolutionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ResolutionsButtonGroup.SelectedObject;
            app.glResolution = selectedButton.Text;
            app.glCamObj.Resolution = char(app.glResolution);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if isempty(app.glDevName)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            if isempty(app.glResolution)
                sl=app.ResolutionsButtonGroup.SelectedObject;
                app.glResolution = sl.Text;
            end
            
            %% Setup Resolution
            app.glCamObj.Resolution = char(app.glResolution);
            
            % Start preview
            preview(app.glCamObj);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AiCamUIFigure = uifigure;
            app.AiCamUIFigure.Name = 'AiCam';
            app.FileMenu = uimenu(app.AiCamUIFigure);
            app.FileMenu.Text = 'File';
            app.DevicesMenu = uimenu(app.AiCamUIFigure);
            app.DevicesMenu.Text = 'Devices';
            app.OptionsMenu = uimenu(app.AiCamUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.CaptureMenu = uimenu(app.AiCamUIFigure);
            app.CaptureMenu.Text = 'Capture';
            app.TabGroup = uitabgroup(app.AiCamUIFigure);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.CameraListListBoxLabel = uilabel(app.PreviewTab);
            app.CameraListListBoxLabel.Text = 'Camera List';
            app.CameraListListBox = uilistbox(app.PreviewTab);
            app.CameraListListBox.Items = {};
            app.CameraListListBox.ValueChangedFcn = createCallbackFcn(app, @CameraListListBoxValueChanged, true);
            app.CameraListListBox.Value = {};
            app.ResolutionsButtonGroup = uibuttongroup(app.PreviewTab);
            app.ResolutionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResolutionsButtonGroupSelectionChanged, true);
            app.ResolutionsButtonGroup.Title = 'Resolutions';
            app.PreviewButton = uibutton(app.PreviewTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CaptureRecordTab = uitab(app.TabGroup);
            app.CaptureRecordTab.Title = 'Capture&Record';
            app.ImageProcessTab = uitab(app.TabGroup);
            app.ImageProcessTab.Title = 'Image Process';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AiCam

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AiCamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AiCamUIFigure)
        end
    end
end